classdef NBS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MainLayout                  matlab.ui.container.GridLayout
        SettingsLayout              matlab.ui.container.GridLayout
        Padding__                   matlab.ui.control.Label
        StatusLabel                 matlab.ui.control.Label
        ControlPanel                matlab.ui.container.Panel
        CtrlLayout                  matlab.ui.container.GridLayout
        NumBodiesField              matlab.ui.control.Spinner
        TrailSlider                 matlab.ui.control.Slider
        TraillengthLabel            matlab.ui.control.Label
        NumberofbodiesLabel         matlab.ui.control.Label
        PresetDropDown              matlab.ui.control.DropDown
        SmoothnessLabel             matlab.ui.control.Label
        TimestepdtLabel             matlab.ui.control.Label
        GravitationalConstantLabel  matlab.ui.control.Label
        GravSlider                  matlab.ui.control.Slider
        TimeStepSlider              matlab.ui.control.Slider
        SoftenSlider                matlab.ui.control.Slider
        PPButton                    matlab.ui.control.StateButton
        ResetButton                 matlab.ui.control.Button
        NBodySimulatiorLabel        matlab.ui.control.Label
        SimAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        N,
        pos,
        vel,
        mass,
        colors,
        radii
        trailX,
        trailY,
        trailLen
        bodyPlots,
        trailLines
        G,
        dt,
        softening,
        simTime,
        stepCount
        simTimer,
        isRunning = false,
        isInit = false
        stepsPerTick
    end
    
methods (Access = private)
        function initSimulation(app)
            app.stopSim();

            app.N = app.NumBodiesField.Value;
            app.G = app.GravSlider.Value;
            app.dt = app.TimeStepSlider.Value;
            app.softening = app.SoftenSlider.Value;
            app.trailLen = round(app.TrailSlider.Value);
            app.simTime = 0;
            app.stepCount = 0;
            app.setupPreset(app.PresetDropDown.Value);
            app.initGraphics();
            app.isInit = true;
            app.updateStats();
        end

        function setupPreset(app, preset)
            G = app.G;
            app.mass = ones(app.N, 1);
            app.pos = zeros(app.N, 2);
            app.vel = zeros(app.N, 2);
            axis(app.SimAxes, 'normal');
            switch preset
                case 'Square4'
                    setupGSdTTick(app, 1, 0, 0.002, 50);
                    app.N = 4;                    

                    app.mass = ones(app.N, 1); 
                    s = 3; % This is the distance from the axis to the corner
                
                    app.pos = zeros(app.N, 2);
                    app.pos(1,:) = [ s,  s]; % Top-Right
                    app.pos(2,:) = [-s,  s]; % Top-Left
                    app.pos(3,:) = [-s, -s]; % Bottom-Left
                    app.pos(4,:) = [ s, -s]; % Bottom-Right
                
                    % The distance from center to corner (Radius) is R = s * sqrt(2)
                    % The required velocity for this 4-body configuration is:
                    R = s * sqrt(2);
                    vMag = sqrt((app.G * 1 / R) * 0.603553);
                    
                    % Velocities must be perpendicular to the vector from the center
                    vComp = vMag / sqrt(2); % The x and y components of the velocity vector
                    
                    app.vel = zeros(app.N, 2);
                    app.vel(1,:) = [-vComp,  vComp]; % Moving Left-Up
                    app.vel(2,:) = [-vComp, -vComp]; % Moving Left-Down
                    app.vel(3,:) = [ vComp, -vComp]; % Moving Right-Down
                    app.vel(4,:) = [ vComp,  vComp]; % Moving Right-Up
                    axis(app.SimAxes, 'equal');
                case 'Yarn'
                    setupGSdTTick(app, 1, 0, 0.001, 100);
                    app.N = 3;                    

                    app.mass = ones(app.N, 1);
                    app.pos = zeros(app.N, 2);
                    app.vel = zeros(app.N, 2);
                    scale = 2;
                    
                    app.G = 1.0;
                    app.GravSlider.Value = 1.0;
                    app.dt = 0.0001;
                    app.TimeStepSlider.Value = 0.0001;
                    app.softening = 0.01;
                    app.SoftenSlider.Value = 0.1;

                    % Yarn canonical starting positions
                    app.pos(1,:) = [-1.0,  0.0] * scale;
                    app.pos(2,:) = [ 1.0,  0.0] * scale;
                    app.pos(3,:) = [ 0.0,  0.0];
                    
                    % Yarn canonical starting velocities
                    v1x = 0.361396;
                    v1y = 0.225728;
                    % Scale velocity dynamically based on the user's Gravity slider
                    vScale = sqrt(G) / sqrt(scale);
                    
                    app.vel(1,:) = [ v1x,    v1y] * vScale;
                    app.vel(2,:) = [ v1x,    v1y] * vScale;
                    app.vel(3,:) = [-2*v1x, -2*v1y] * vScale;

                case 'Figure8'
                    setupGSdTTick(app, 1, 0.01, 0.0001, 200);
                    app.N = 3;

                    app.TrailSlider.Value = 300;                    
                    app.trailLen = round(app.TrailSlider.Value);
                    scale = 2;
                    
                    app.NumBodiesField.Value = 3;

                    % Set app.N again
                    app.mass = ones(app.N, 1); 
                    app.pos = zeros(app.N, 2);
                    app.vel = zeros(app.N, 2);

                    app.mass = ones(3, 1);

                    % Canonical figure-8 coordinates
                    app.pos(1,:) = [ 0.97000436,  -0.24308753] * scale;
                    app.pos(2,:) = [-0.97000436,   0.24308753] * scale;
                    app.pos(3,:) = [ 0.0,          0.0];

                    % Canonical figure-8 velocities
                    v3x = -0.93240737;
                    v3y = -0.86473146;
                    vScale = 1 / sqrt(scale);
                    app.vel(3,:) = [v3x,      v3y    ] * vScale;
                    app.vel(1,:) = [-v3x/2,  -v3y/2  ] * vScale;
                    app.vel(2,:) = [-v3x/2,  -v3y/2  ] * vScale;
                case 'Random Cluster'
                    setupGSdTTick(app, 1, 0, 0.01, 10);
                    R = 25;                    
                    app.pos = (rand(app.N, 2) - 0.5) * 2 * R;
                    vScale = sqrt(G * app.N / R) * 0.4;
                    app.vel = (rand(app.N, 2) - 0.5) * vScale;
                case 'Solar System'
                    setupGSdTTick(app, 1, 0, 0.001, 20);
                    app.N = min(app.N, 12);

                    app.mass = ones(app.N, 1) * 0.2;
                    app.mass(1) = 500;
                    app.pos = zeros(app.N, 2);
                    app.vel = zeros(app.N, 2);
                    for i = 2:app.N
                        r = 12 + (i-2) * 10;
                        theta = rand() * 2 * pi;
                        app.pos(i,:) = [r*cos(theta), r*sin(theta)];
                        v = sqrt(G * app.mass(1) / r);
                        app.vel(i,:) = [-sin(theta), cos(theta)] * v;
                    end
                case 'Binary System'
                    setupGSdTTick(app, 1, 0.1, 0.002, 40);

                    mStar = 300;
                    sep = 15;
                    app.mass = ones(app.N, 1) * 0.2;
                    app.mass(1:2) = mStar;
                    app.pos(1,:) = [-sep, 0];
                    app.pos(2,:) = [sep, 0];
                    vOrbit = sqrt(G * mStar / (4 * sep));
                    app.vel(1,:) = [0, vOrbit]; app.vel(2,:) = [0, -vOrbit];
                    for i = 3:app.N
                        r = 35 + rand() * 20;
                        th = rand() * 2 * pi;
                        app.pos(i,:) = [r*cos(th), r*sin(th)];
                        app.vel(i,:) = [-sin(th), cos(th)] * sqrt(G * 2 * mStar / r);
                    end
            end
            app.NumBodiesField.Value = app.N;

            if ~any(strcmp(preset, {'Figure8', 'Square4', 'Yarn'}))
                comV = sum(app.mass .* app.vel, 1) / sum(app.mass);
                app.vel = app.vel - comV;
            end
            app.colors = hsv(app.N);
            app.radii = (5 + 12 * (app.mass / max(app.mass)).^(1/3)).^2;
            app.trailX = cell(app.N,1); app.trailY = cell(app.N,1);
            for i=1:app.N
                app.trailX{i} = app.pos(i, 1);
                app.trailY{i} = app.pos(i, 2);
            end
        end

        function initGraphics(app)
            cla(app.SimAxes);
            hold(app.SimAxes, 'on');
            
            % The stars
            starCount = 100;     
            % Calculate the same limit updateGraphics uses
            limit = max(max(abs(app.pos))) * 1.3 + 10;
            % Spawn stars within that limit
            sPos = (rand(starCount, 2) - 0.5) * 2 * limit;
            scatter(app.SimAxes, sPos(:,1), sPos(:,2), 3, [0.3 0.3 0.5], 'filled');

            app.trailLines = gobjects(app.N,1);
            for i = 1:app.N
                app.trailLines(i) = line(app.SimAxes, app.pos(i,1), app.pos(i,2), 'Color', [app.colors(i,:), 0.4], 'LineWidth', 1.2);
            end
            app.bodyPlots = scatter(app.SimAxes, app.pos(:,1), app.pos(:,2), app.radii, app.colors, 'filled', 'MarkerEdgeColor', 'none');
            hold(app.SimAxes, 'off');
        end

        function stepSimulation(app)
            m = app.mass;
            G = app.G;
            dt = app.dt;
            e2 = app.softening^2;
            a1 = nbodyAcc(app, app.pos, m, G, e2);
            vMid = app.vel + a1 * (dt/2);
            app.pos = app.pos + vMid * dt;
            a2 = nbodyAcc(app, app.pos, m, G, e2);
            app.vel = vMid + a2 * (dt/2);
            app.simTime = app.simTime + dt;
            app.stepCount = app.stepCount + 1;
        end

        function updateGraphics(app)
            if ~isvalid(app.UIFigure)
                return;
            end
            for i = 1:app.N
                app.trailX{i}(end + 1) = app.pos(i, 1);
                app.trailY{i}(end + 1) = app.pos(i, 2);
                if numel(app.trailX{i}) > app.trailLen
                    app.trailX{i}(1) = [];
                    app.trailY{i}(1) = [];
                end
                set(app.trailLines(i), 'XData', app.trailX{i}, 'YData', app.trailY{i});
            end
            set(app.bodyPlots, 'XData', app.pos(:,1), 'YData', app.pos(:,2));

            if mod(app.stepCount, 10) == 0
                limit = max(max(abs(app.pos))) * 1.3 + 10;
                axis(app.SimAxes, [-limit limit -limit limit]);
            end
            drawnow limitrate;
        end

        function updateStats(app)
            %ke = 0.5 * sum(app.mass .* sum(app.vel.^2, 2));
            %app.KineticValue.Text = sprintf('KE: %.2f', ke);
            %app.SimTimeValue.Text = sprintf('Time: %.2f', app.simTime);
            %app.StepValue.Text = sprintf('Steps: %d', app.stepCount);
        end

        % Main simulation loop
        function timerFcn(app, ~, ~)
            if ~app.isRunning || ~isvalid(app.UIFigure)
                return;
            end
            try
                for k = 1:app.stepsPerTick
                    app.stepSimulation();
                end
                app.updateGraphics();
                if mod(app.stepCount, 40) == 0
                    app.updateStats();
                end
            catch
                app.stopSim();
            end
        end
        
        function startSim(app)
            if ~app.isInit
                app.initSimulation();
            end

            app.isRunning = true;
            % The fix: only start if it's currently stopped
            if isvalid(app.simTimer) && strcmp(app.simTimer.Running, 'off')
                start(app.simTimer);
            end
        end

        function stopSim(app)
            app.isRunning = false;
            if isvalid(app.simTimer) && strcmp(app.simTimer.Running, 'on')
                stop(app.simTimer);
            end
        end        
        
        function acc = nbodyAcc(~, pos, m, G, e2)
             N = size(pos,1);
             dx = pos(:,1)' - pos(:,1); 
             dy = pos(:,2)' - pos(:,2);
             r2 = dx.^2 + dy.^2 + e2;
             invR3 = r2.^(-1.5);
             invR3(1:N + 1:end) = 0; 
             fac = G * (m') .* invR3;
             acc = [sum(fac.*dx, 2), sum(fac.*dy, 2)];
         end
        
        function rgbColor = colorToNormalizedRGB(~, color)
            rgbColor = color ./ 255;
        end
        
        function setupGSdTTick(app, G, softening, dt, stepsPerTick)
            app.G = G;
            app.GravSlider.Value = app.G;
            app.softening = softening;
            app.SoftenSlider.Value = app.softening;
            app.dt = dt; 
            app.TimeStepSlider.Value = app.dt;
            app.stepsPerTick = stepsPerTick;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.simTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.033, 'TimerFcn', @app.timerFcn, 'BusyMode', 'drop');
            app.initSimulation();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.stopSim();
            if isvalid(app.simTimer)
                delete(app.simTimer);
            end
            delete(app);
        end

        % Callback function: GravSlider, NumBodiesField, PresetDropDown, 
        % ...and 4 other components
        function OnResetPressed(app, event)
            if app.PPButton.Value == 1
                app.PPButton.Value = 0;
                OnPPPressed(app, event);
            end
            app.initSimulation();
        end

        % Value changed function: PPButton
        function OnPPPressed(app, event)
            value = app.PPButton.Value;
            if value == 1 % VALUE 1 means sim is stopped, start the simulation
                app.startSim();
                app.PPButton.Text = "PAUSE";
                app.PPButton.BackgroundColor = colorToNormalizedRGB(app, [153, 153, 153]);
                app.ControlPanel.Visible = "off";
                app.StatusLabel.Text = "SIMULATION IS RUNNING";
            else % VALUE 0 means sim is currently running, stop the simulation
                app.stopSim();
                app.PPButton.Text = "RUN";
                app.PPButton.BackgroundColor = colorToNormalizedRGB(app, [153, 255, 51]);
                app.ControlPanel.Visible = "on";
                app.StatusLabel.Text = "";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MainLayout = uigridlayout(app.UIFigure);
            app.SimAxes = uiaxes(app.MainLayout);
            title(app.SimAxes, 'SIMULATION')
            app.SimAxes.XTick = [];
            app.SimAxes.YTick = [];
            app.SimAxes.ZTick = [];
            app.SettingsLayout = uigridlayout(app.MainLayout);
            app.NBodySimulatiorLabel = uilabel(app.SettingsLayout);
            app.NBodySimulatiorLabel.Text = 'N-BodySimulatior';
            app.ResetButton = uibutton(app.SettingsLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.ResetButton.Text = 'STOP';
            app.PPButton = uibutton(app.SettingsLayout, 'state');
            app.PPButton.ValueChangedFcn = createCallbackFcn(app, @OnPPPressed, true);
            app.PPButton.Text = 'RUN';
            app.ControlPanel = uipanel(app.SettingsLayout);
            app.CtrlLayout = uigridlayout(app.ControlPanel);
            app.SoftenSlider = uislider(app.CtrlLayout);
            app.SoftenSlider.Limits = [0 5];
            app.SoftenSlider.MajorTicks = [0.001 0.5 0.8 1.33 1.74 5];
            app.SoftenSlider.MajorTickLabels = {'0.001', '0.5', '0.8', '1.33', '1.74', '5'};
            app.SoftenSlider.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.SoftenSlider.MinorTicks = [];
            app.SoftenSlider.Value = 0.8;
            app.TimeStepSlider = uislider(app.CtrlLayout);
            app.TimeStepSlider.Limits = [0.0001 0.1];
            app.TimeStepSlider.MajorTicks = [0.0001 0.03 0.058 0.1];
            app.TimeStepSlider.MajorTickLabels = {'1e-4', '0.03', '0.058', '0.1'};
            app.TimeStepSlider.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.TimeStepSlider.MinorTicks = [];
            app.TimeStepSlider.Value = 0.0001;
            app.GravSlider = uislider(app.CtrlLayout);
            app.GravSlider.Limits = [0.5 5];
            app.GravSlider.MajorTicks = [0.5 1 2 3 4 5];
            app.GravSlider.MajorTickLabels = {'0.5', '1', '2', '3', '4', '5'};
            app.GravSlider.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.GravSlider.MinorTicks = [];
            app.GravSlider.Value = 1;
            app.GravitationalConstantLabel = uilabel(app.CtrlLayout);
            app.GravitationalConstantLabel.Text = 'Gravitational Constant';
            app.TimestepdtLabel = uilabel(app.CtrlLayout);
            app.TimestepdtLabel.Text = 'Time step (dt)';
            app.SmoothnessLabel = uilabel(app.CtrlLayout);
            app.SmoothnessLabel.Text = 'Smoothness (ε)';
            app.PresetDropDown = uidropdown(app.CtrlLayout);
            app.PresetDropDown.Items = {'Figure8', 'Yarn', 'Square4', 'Random Cluster', 'Solar System', 'Binary System'};
            app.PresetDropDown.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.PresetDropDown.Value = 'Square4';
            app.NumberofbodiesLabel = uilabel(app.CtrlLayout);
            app.NumberofbodiesLabel.Text = 'Number of bodies';
            app.TraillengthLabel = uilabel(app.CtrlLayout);
            app.TraillengthLabel.Text = 'Trail length';
            app.TrailSlider = uislider(app.CtrlLayout);
            app.TrailSlider.Limits = [5 3000];
            app.TrailSlider.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.TrailSlider.Value = 150;
            app.NumBodiesField = uispinner(app.CtrlLayout);
            app.NumBodiesField.Limits = [2 30];
            app.NumBodiesField.ValueDisplayFormat = '%.0f';
            app.NumBodiesField.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.NumBodiesField.Tooltip = {'Number of celestial bodies in the simulation'; 'Minimum: 2'; 'Maximum: 15'};
            app.NumBodiesField.Value = 8;
            app.StatusLabel = uilabel(app.SettingsLayout);
            app.StatusLabel.Text = '';
            app.Padding__ = uilabel(app.SettingsLayout);
            app.Padding__.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NBS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end